classdef GUI_David_OH_Capstone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        RobotVisualizationTab        matlab.ui.container.Tab
        Theta4EditField_2            matlab.ui.control.NumericEditField
        Theta4EditField_2Label       matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        EndEffectorPositionOrientationLabel  matlab.ui.control.Label
        InitialGuessforJointAnglesLabel  matlab.ui.control.Label
        Theta3EditField_2            matlab.ui.control.NumericEditField
        Theta3EditField_2Label       matlab.ui.control.Label
        Theta2EditField_2            matlab.ui.control.NumericEditField
        Theta2EditField_2Label       matlab.ui.control.Label
        Theta1EditField_2            matlab.ui.control.NumericEditField
        Theta1EditField_2Label       matlab.ui.control.Label
        Theta4EditField              matlab.ui.control.NumericEditField
        Theta4EditFieldLabel         matlab.ui.control.Label
        Theta3EditField              matlab.ui.control.NumericEditField
        Theta3EditFieldLabel         matlab.ui.control.Label
        Theta2EditField              matlab.ui.control.NumericEditField
        Theta2EditFieldLabel         matlab.ui.control.Label
        Theta1EditField              matlab.ui.control.NumericEditField
        Theta1EditFieldLabel         matlab.ui.control.Label
        ForwardKinematicsButton      matlab.ui.control.Button
        InverseKinematicsButton      matlab.ui.control.Button
        JointAnglesLabel             matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        RobotManipulatorControlTab   matlab.ui.container.Tab
        GenerateCommPortsButton      matlab.ui.control.Button
        StartButton_2                matlab.ui.control.Button
        InitialGuessforJointAnglesLabel_2  matlab.ui.control.Label
        Theta3EditField_3            matlab.ui.control.NumericEditField
        Theta3EditField_3Label       matlab.ui.control.Label
        Theta2EditField_3            matlab.ui.control.NumericEditField
        Theta2EditField_3Label       matlab.ui.control.Label
        Theta1EditField_3            matlab.ui.control.NumericEditField
        Theta1EditField_3Label       matlab.ui.control.Label
        IgainEditField               matlab.ui.control.NumericEditField
        IgainEditFieldLabel          matlab.ui.control.Label
        DgainEditField               matlab.ui.control.NumericEditField
        DgainEditFieldLabel          matlab.ui.control.Label
        PgainEditField               matlab.ui.control.NumericEditField
        PgainEditFieldLabel          matlab.ui.control.Label
        PIDControlParametersLabel    matlab.ui.control.Label
        ZPosEditField                matlab.ui.control.NumericEditField
        ZPosEditFieldLabel           matlab.ui.control.Label
        Angle4EditField_2            matlab.ui.control.NumericEditField
        Angle4EditField_2Label       matlab.ui.control.Label
        YPosEditField                matlab.ui.control.NumericEditField
        YPosEditFieldLabel           matlab.ui.control.Label
        XPosEditField                matlab.ui.control.NumericEditField
        XPosEditFieldLabel           matlab.ui.control.Label
        InverseKinematicsLabel       matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        Angle3EditField              matlab.ui.control.NumericEditField
        Angle3EditFieldLabel         matlab.ui.control.Label
        Angle4EditField              matlab.ui.control.NumericEditField
        Angle4EditFieldLabel         matlab.ui.control.Label
        Angle2EditField              matlab.ui.control.NumericEditField
        Angle2EditFieldLabel         matlab.ui.control.Label
        Angle1EditField              matlab.ui.control.NumericEditField
        Angle1EditFieldLabel         matlab.ui.control.Label
        ForwardKinematicsLabel       matlab.ui.control.Label
        ArduinoConnectionSetupLabel  matlab.ui.control.Label
        ConnectButton                matlab.ui.control.Button
        BaudRateDropDown             matlab.ui.control.DropDown
        BaudRateDropDownLabel        matlab.ui.control.Label
        CommPortDropDown             matlab.ui.control.DropDown
        CommPortDropDownLabel        matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        PositionControlGraphsTab     matlab.ui.container.Tab
        UIAxes3_4                    matlab.ui.control.UIAxes
        UIAxes3_3                    matlab.ui.control.UIAxes
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Populate commPortDropdown with available COM ports
            availablePorts = serialportlist("available");
            app.CommPortDropDown.Items = availablePorts;
        end

        % Button pushed function: ForwardKinematicsButton
        function ForwardKinematicsButtonPushed(app, event)
            Th_1 = app.Theta1EditField.Value * -pi/180;
            Th_2 = app.Theta2EditField.Value * -pi/180;
            Th_3 = app.Theta3EditField.Value * -pi/180;
            Th_4 = app.Theta4EditField.Value * -pi/180;
            a1 = 23.13;
            a2 = 146.66;
            a3 = 119.19;
            a4 = 154.27;
            d1 = 194.79;
            d2 = 32.8;
            d3 = -30.67;
            d4 = 0;
            alpha1 = -90;
            alpha2 = 0;
            alpha3 = 0;
            alpha4 = 0;
            
            L(1) = Link([0 d1 a1 alpha1]);
            L(2) = Link([0 d2 a2 alpha2]);
            L(3) = Link([0 d3 a3 alpha3]);
            L(4) = Link([0 d4 a4 alpha4]);

            Robot = SerialLink(L);
            Robot.name = 'Robotic Arm';

            % Create a hidden figure
            fig = figure('Visible', 'off');
            
            % Plot the robot in the hidden figure
            Robot.plot([Th_1 Th_2 Th_3 Th_4]);
            
            % Get the handle to the robot plot
            h = findobj(fig, 'Type', 'Axes');
            
            % Clear existing plot in the GUI axes
            cla(app.UIAxes, 'reset');
            
            % Copy the plot to the app UIAxes
            for i = 1:length(h)
                copyobj(allchild(h(i)), app.UIAxes);
            end

            % Set the view to isometric
            view(app.UIAxes, 3);

            % Enable grid
            grid(app.UIAxes, 'on');
            
            % Close the hidden figure
            close(fig);

            syms theta a d alphaa;
            Hom_rot = [cosd(theta) -sind(theta)*cosd(alphaa) sind(theta)*sind(alphaa) a*cosd(theta);
                sind(theta) cosd(theta)*cosd(alphaa) -cosd(theta)*sind(alphaa) a*sind(theta);
                0 sind(alphaa) cosd(alphaa) d;
                0 0 0 1];
            
            syms theta1 theta2 theta3 theta4
            
            H1 = [subs(Hom_rot, [theta a d alphaa], [theta1 a1 d1 alpha1])];
            H2 = [subs(Hom_rot, [theta a d alphaa], [theta2 a2 d2 alpha2])];
            H3 = [subs(Hom_rot, [theta a d alphaa], [theta3 a3 d3 alpha3])];
            H4 = [subs(Hom_rot, [theta a d alphaa], [theta4 a4 d4 alpha4])];
            
            Hom_tot = H1*H2*H3*H4;
            
            
            angle_1 = app.Theta1EditField.Value;
            angle_2 = app.Theta2EditField.Value;
            angle_3 = app.Theta3EditField.Value;
            angle_4 = app.Theta4EditField.Value;
            
            Hom_tot_ans = double(subs(Hom_tot, {theta1 theta2 theta3 theta4}, {angle_1 angle_2 angle_3 angle_4}));
            
            app.XEditField.Value = Hom_tot_ans(1, 4);
            app.YEditField.Value = Hom_tot_ans(2, 4);
            app.ZEditField.Value = Hom_tot_ans(3, 4);
            app.Theta4EditField_2.Value = app.Theta4EditField.Value;

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global a

            % Clear existing Arduino object if it exists
            if ~isempty(a)
                clear a;
            end
            
            % Retrieve selected COM port and baud rate% Retrieve selected
            % COM port and baud rate
            selectedPort = app.CommPortDropDown.Value;
            selectedBaudRate = str2double(app.BaudRateDropDown.Value);
            
            % Initialize Arduino with selected COM port and baud rate
            a = arduino(selectedPort, 'Uno', 'BaudRate', selectedBaudRate);
        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            % Store X, Y, and Z positions:
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;          

            a1 = 23.13;
            a2 = 146.66;
            a3 = 119.19;
            a4 = 154.27;
            d1 = 194.79;
            d2 = 32.8;
            d3 = -30.67;
            d4 = 0;
            alpha1 = -90;
            alpha2 = 0;
            alpha3 = 0;
            alpha4 = 0;
            
            L(1) = Link([0 d1 a1 alpha1]);
            L(2) = Link([0 d2 a2 alpha2]);
            L(3) = Link([0 d3 a3 alpha3]);
            L(4) = Link([0 d4 a4 alpha4]);

            Robot = SerialLink(L);
            Robot.name = 'Robotic Arm';

            
            % Optimization Algorithms:
            options = optimoptions(@fsolve, 'Algorithm', 'trust-region', 'UseParallel', false, 'OptimalityTolerance', 1e-8, 'FunctionTolerance', 1e-12); 
            
            initialGuess = [app.Theta1EditField_2.Value; app.Theta2EditField_2.Value; app.Theta3EditField_2.Value; app.Theta4EditField_2.Value];
            
            [SolutionAngles] = fsolve(@(theta)Kinematics_Function(theta, x, y, z, app.Theta4EditField_2.Value), initialGuess, options);

            app.Theta1EditField.Value = round(SolutionAngles(1));
            app.Theta2EditField.Value = round(SolutionAngles(2));
            app.Theta3EditField.Value = round(SolutionAngles(3));
            app.Theta4EditField.Value = round(SolutionAngles(4));

            Th_1 = round(SolutionAngles(1)) * -pi/180;
            Th_2 = round(SolutionAngles(2)) * -pi/180;
            Th_3 = round(SolutionAngles(3)) * -pi/180;
            Th_4 = round(SolutionAngles(4)) * -pi/180;

            % Create a hidden figure
            fig = figure('Visible', 'off');
            
            % Plot the robot in the hidden figure
            Robot.plot([Th_1 Th_2 Th_3 Th_4]);
            
            % Get the handle to the robot plot
            h = findobj(fig, 'Type', 'Axes');
            
            % Clear existing plot in the GUI axes
            cla(app.UIAxes, 'reset');
            
            % Copy the plot to the app UIAxes
            for i = 1:length(h)
                copyobj(allchild(h(i)), app.UIAxes);
            end

            % Set the view to isometric
            view(app.UIAxes, 3);

            % Enable grid
            grid(app.UIAxes, 'on');
            
            % Close the hidden figure
            close(fig);
            

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            Th_1 = app.Angle1EditField.Value * -pi/180;
            Th_2 = app.Angle2EditField.Value * -pi/180;
            Th_3 = app.Angle3EditField.Value * -pi/180;
            Th_4 = app.Angle4EditField.Value * -pi/180;
            a1 = 23.13;
            a2 = 146.66;
            a3 = 119.19;
            a4 = 154.27;
            d1 = 194.79;
            d2 = 32.8;
            d3 = -30.67;
            d4 = 0;
            alpha1 = -90;
            alpha2 = 0;
            alpha3 = 0;
            alpha4 = 0;
            
            L(1) = Link([0 d1 a1 alpha1]);
            L(2) = Link([0 d2 a2 alpha2]);
            L(3) = Link([0 d3 a3 alpha3]);
            L(4) = Link([0 d4 a4 alpha4]);

            Robot = SerialLink(L);
            Robot.name = 'Robotic Arm';

            % Create a hidden figure
            fig = figure('Visible', 'off');
            
            % Plot the robot in the hidden figure
            Robot.plot([Th_1 Th_2 Th_3 Th_4]);
            
            % Get the handle to the robot plot
            h = findobj(fig, 'Type', 'Axes');
            
            % Clear existing plot in the GUI axes
            cla(app.UIAxes2, 'reset');
            
            % Copy the plot to the app UIAxes
            for i = 1:length(h)
                copyobj(allchild(h(i)), app.UIAxes2);
            end

            % Set the view to isometric
            view(app.UIAxes2, 3);

            % Enable grid
            grid(app.UIAxes2, 'on');
            
            % Close the hidden figure
            close(fig);

        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
             % Store X, Y, and Z positions:
            x = app.XPosEditField.Value;
            y = app.YPosEditField.Value;
            z = app.ZPosEditField.Value;          

            a1 = 23.13;
            a2 = 146.66;
            a3 = 119.19;
            a4 = 154.27;
            d1 = 194.79;
            d2 = 32.8;
            d3 = -30.67;
            d4 = 0;
            alpha1 = -90;
            alpha2 = 0;
            alpha3 = 0;
            alpha4 = 0;
            
            L(1) = Link([0 d1 a1 alpha1]);
            L(2) = Link([0 d2 a2 alpha2]);
            L(3) = Link([0 d3 a3 alpha3]);
            L(4) = Link([0 d4 a4 alpha4]);

            Robot = SerialLink(L);
            Robot.name = 'Robotic Arm';

            
            % Optimization Algorithms:
            options = optimoptions(@fsolve, 'Algorithm', 'trust-region', 'UseParallel', false, 'OptimalityTolerance', 1e-8, 'FunctionTolerance', 1e-12); 
            
            initialGuess = [app.Theta1EditField_3.Value; app.Theta2EditField_3.Value; app.Theta3EditField_3.Value; app.Angle4EditField_2.Value];
            
            [SolutionAngles] = fsolve(@(theta)Kinematics_Function(theta, x, y, z, app.Angle4EditField_2.Value), initialGuess, options);

            Th_1 = round(SolutionAngles(1)) * -pi/180;
            Th_2 = round(SolutionAngles(2)) * -pi/180;
            Th_3 = round(SolutionAngles(3)) * -pi/180;
            Th_4 = round(SolutionAngles(4)) * -pi/180;

            % Create a hidden figure
            fig = figure('Visible', 'off');
            
            % Plot the robot in the hidden figure
            Robot.plot([Th_1 Th_2 Th_3 Th_4]);
            
            % Get the handle to the robot plot
            h = findobj(fig, 'Type', 'Axes');
            
            % Clear existing plot in the GUI axes
            cla(app.UIAxes2, 'reset');
            
            % Copy the plot to the app UIAxes
            for i = 1:length(h)
                copyobj(allchild(h(i)), app.UIAxes2);
            end

            % Set the view to isometric
            view(app.UIAxes2, 3);

            % Enable grid
            grid(app.UIAxes2, 'on');
            
            % Close the hidden figure
            close(fig);
            
        end

        % Button pushed function: GenerateCommPortsButton
        function GenerateCommPortsButtonPushed(app, event)
            % Populate commPortDropdown with available COM ports
            availablePorts = serialportlist("available");
            app.CommPortDropDown.Items = availablePorts;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RobotVisualizationTab = uitab(app.TabGroup);
            app.RobotVisualizationTab.Title = 'Robot Visualization';
            app.UIAxes = uiaxes(app.RobotVisualizationTab);
            title(app.UIAxes, 'Robot Visualization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.JointAnglesLabel = uilabel(app.RobotVisualizationTab);
            app.JointAnglesLabel.Text = 'Joint Angles';
            app.InverseKinematicsButton = uibutton(app.RobotVisualizationTab, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.ForwardKinematicsButton = uibutton(app.RobotVisualizationTab, 'push');
            app.ForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButtonPushed, true);
            app.ForwardKinematicsButton.Text = 'Forward Kinematics';
            app.Theta1EditFieldLabel = uilabel(app.RobotVisualizationTab);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.RobotVisualizationTab, 'numeric');
            app.Theta2EditFieldLabel = uilabel(app.RobotVisualizationTab);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.RobotVisualizationTab, 'numeric');
            app.Theta3EditFieldLabel = uilabel(app.RobotVisualizationTab);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.RobotVisualizationTab, 'numeric');
            app.Theta4EditFieldLabel = uilabel(app.RobotVisualizationTab);
            app.Theta4EditFieldLabel.Text = 'Theta 4';
            app.Theta4EditField = uieditfield(app.RobotVisualizationTab, 'numeric');
            app.Theta1EditField_2Label = uilabel(app.RobotVisualizationTab);
            app.Theta1EditField_2Label.Text = 'Theta 1';
            app.Theta1EditField_2 = uieditfield(app.RobotVisualizationTab, 'numeric');
            app.Theta2EditField_2Label = uilabel(app.RobotVisualizationTab);
            app.Theta2EditField_2Label.Text = 'Theta 2';
            app.Theta2EditField_2 = uieditfield(app.RobotVisualizationTab, 'numeric');
            app.Theta3EditField_2Label = uilabel(app.RobotVisualizationTab);
            app.Theta3EditField_2Label.Text = 'Theta 3';
            app.Theta3EditField_2 = uieditfield(app.RobotVisualizationTab, 'numeric');
            app.InitialGuessforJointAnglesLabel = uilabel(app.RobotVisualizationTab);
            app.InitialGuessforJointAnglesLabel.Text = 'Initial Guess for Joint Angles';
            app.EndEffectorPositionOrientationLabel = uilabel(app.RobotVisualizationTab);
            app.EndEffectorPositionOrientationLabel.Text = 'End Effector Position & Orientation';
            app.XEditFieldLabel = uilabel(app.RobotVisualizationTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.RobotVisualizationTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.RobotVisualizationTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RobotVisualizationTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.RobotVisualizationTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.RobotVisualizationTab, 'numeric');
            app.Theta4EditField_2Label = uilabel(app.RobotVisualizationTab);
            app.Theta4EditField_2Label.Text = 'Theta 4';
            app.Theta4EditField_2 = uieditfield(app.RobotVisualizationTab, 'numeric');
            app.RobotManipulatorControlTab = uitab(app.TabGroup);
            app.RobotManipulatorControlTab.Title = 'Robot Manipulator Control';
            app.UIAxes2 = uiaxes(app.RobotManipulatorControlTab);
            title(app.UIAxes2, 'Robot Visualizer')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CommPortDropDownLabel = uilabel(app.RobotManipulatorControlTab);
            app.CommPortDropDownLabel.Text = 'Comm Port';
            app.CommPortDropDown = uidropdown(app.RobotManipulatorControlTab);
            app.BaudRateDropDownLabel = uilabel(app.RobotManipulatorControlTab);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.RobotManipulatorControlTab);
            app.BaudRateDropDown.Items = {'9600', '19200', '38400', '57600', '115200', '250000', '500000'};
            app.BaudRateDropDown.Value = '9600';
            app.ConnectButton = uibutton(app.RobotManipulatorControlTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ArduinoConnectionSetupLabel = uilabel(app.RobotManipulatorControlTab);
            app.ArduinoConnectionSetupLabel.Text = 'Arduino Connection Setup';
            app.ForwardKinematicsLabel = uilabel(app.RobotManipulatorControlTab);
            app.ForwardKinematicsLabel.Text = 'Forward Kinematics';
            app.Angle1EditFieldLabel = uilabel(app.RobotManipulatorControlTab);
            app.Angle1EditFieldLabel.Text = 'Angle 1';
            app.Angle1EditField = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.Angle2EditFieldLabel = uilabel(app.RobotManipulatorControlTab);
            app.Angle2EditFieldLabel.Text = 'Angle 2';
            app.Angle2EditField = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.Angle4EditFieldLabel = uilabel(app.RobotManipulatorControlTab);
            app.Angle4EditFieldLabel.Text = 'Angle 4';
            app.Angle4EditField = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.Angle3EditFieldLabel = uilabel(app.RobotManipulatorControlTab);
            app.Angle3EditFieldLabel.Text = 'Angle 3';
            app.Angle3EditField = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.StartButton = uibutton(app.RobotManipulatorControlTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.InverseKinematicsLabel = uilabel(app.RobotManipulatorControlTab);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics';
            app.XPosEditFieldLabel = uilabel(app.RobotManipulatorControlTab);
            app.XPosEditFieldLabel.Text = 'X Pos';
            app.XPosEditField = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.YPosEditFieldLabel = uilabel(app.RobotManipulatorControlTab);
            app.YPosEditFieldLabel.Text = 'Y Pos';
            app.YPosEditField = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.Angle4EditField_2Label = uilabel(app.RobotManipulatorControlTab);
            app.Angle4EditField_2Label.Text = 'Angle 4';
            app.Angle4EditField_2 = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.ZPosEditFieldLabel = uilabel(app.RobotManipulatorControlTab);
            app.ZPosEditFieldLabel.Text = 'Z Pos';
            app.ZPosEditField = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.PIDControlParametersLabel = uilabel(app.RobotManipulatorControlTab);
            app.PIDControlParametersLabel.Text = 'PID Control Parameters';
            app.PgainEditFieldLabel = uilabel(app.RobotManipulatorControlTab);
            app.PgainEditFieldLabel.Text = 'P gain';
            app.PgainEditField = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.DgainEditFieldLabel = uilabel(app.RobotManipulatorControlTab);
            app.DgainEditFieldLabel.Text = 'D gain';
            app.DgainEditField = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.IgainEditFieldLabel = uilabel(app.RobotManipulatorControlTab);
            app.IgainEditFieldLabel.Text = 'I gain';
            app.IgainEditField = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.Theta1EditField_3Label = uilabel(app.RobotManipulatorControlTab);
            app.Theta1EditField_3Label.Text = 'Theta 1';
            app.Theta1EditField_3 = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.Theta2EditField_3Label = uilabel(app.RobotManipulatorControlTab);
            app.Theta2EditField_3Label.Text = 'Theta 2';
            app.Theta2EditField_3 = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.Theta3EditField_3Label = uilabel(app.RobotManipulatorControlTab);
            app.Theta3EditField_3Label.Text = 'Theta 3';
            app.Theta3EditField_3 = uieditfield(app.RobotManipulatorControlTab, 'numeric');
            app.InitialGuessforJointAnglesLabel_2 = uilabel(app.RobotManipulatorControlTab);
            app.InitialGuessforJointAnglesLabel_2.Text = 'Initial Guess for Joint Angles';
            app.StartButton_2 = uibutton(app.RobotManipulatorControlTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.GenerateCommPortsButton = uibutton(app.RobotManipulatorControlTab, 'push');
            app.GenerateCommPortsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCommPortsButtonPushed, true);
            app.GenerateCommPortsButton.Text = 'Generate Comm Ports';
            app.PositionControlGraphsTab = uitab(app.TabGroup);
            app.PositionControlGraphsTab.Title = 'Position Control Graphs';
            app.UIAxes3 = uiaxes(app.PositionControlGraphsTab);
            title(app.UIAxes3, 'Actuatuor 1')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.PositionControlGraphsTab);
            title(app.UIAxes3_2, 'Actuatuor 2')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_3 = uiaxes(app.PositionControlGraphsTab);
            title(app.UIAxes3_3, 'Actuatuor 3')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_4 = uiaxes(app.PositionControlGraphsTab);
            title(app.UIAxes3_4, 'Actuatuor 4')
            xlabel(app.UIAxes3_4, 'X')
            ylabel(app.UIAxes3_4, 'Y')
            zlabel(app.UIAxes3_4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_David_OH_Capstone

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end